classdef FCXD_PGfork_Rewardfork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RewardonlydurationsEditField    matlab.ui.control.NumericEditField
        RewardonlydurationsEditFieldLabel  matlab.ui.control.Label
        ResetROIButton                  matlab.ui.control.Button
        DrawROIButton                   matlab.ui.control.Button
        RunPGCheckBox                   matlab.ui.control.CheckBox
        PreviewPGButton                 matlab.ui.control.Button
        HelpRemindersButton             matlab.ui.control.Button
        PostexperimentholdtimesEditField  matlab.ui.control.NumericEditField
        PostexperimentholdtimesEditFieldLabel  matlab.ui.control.Label
        DoMiniscopeEditField            matlab.ui.control.Label
        DoMiniscope                     matlab.ui.control.CheckBox
        EasterEgg                       matlab.ui.control.Button
        LaunchExperimentButton          matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tone1Tab                        matlab.ui.container.Tab
        TonefreqHzEditField             matlab.ui.control.NumericEditField
        TonefreqHzEditFieldLabel        matlab.ui.control.Label
        Tone2Tab                        matlab.ui.container.Tab
        TonefreqHzEditField_2           matlab.ui.control.NumericEditField
        TonefreqHzEditField_2Label      matlab.ui.control.Label
        FM1Tab                          matlab.ui.container.Tab
        FMSweepdursEditField_2          matlab.ui.control.NumericEditField
        FMSweepdursEditField_2Label     matlab.ui.control.Label
        FMSweependHzEditField_2         matlab.ui.control.NumericEditField
        FMSweependHzEditField_2Label    matlab.ui.control.Label
        FMSweepstartHzEditField_2       matlab.ui.control.NumericEditField
        FMSweepstartHzEditField_2Label  matlab.ui.control.Label
        FM2Tab                          matlab.ui.container.Tab
        FMSweepdursEditField_3          matlab.ui.control.NumericEditField
        FMSweepdursEditField_3Label     matlab.ui.control.Label
        FMSweependHzEditField_3         matlab.ui.control.NumericEditField
        FMSweependHzEditField_3Label    matlab.ui.control.Label
        FMSweepstartHzEditField_3       matlab.ui.control.NumericEditField
        FMSweepstartHzEditField_3Label  matlab.ui.control.Label
        AddeventButton                  matlab.ui.control.Button
        WithlaserButtonGroup            matlab.ui.container.ButtonGroup
        YeslaserButton                  matlab.ui.control.ToggleButton
        NolaserButton                   matlab.ui.control.ToggleButton
        ChooseCSButtonGroup             matlab.ui.container.ButtonGroup
        NoCSButton_2                    matlab.ui.control.ToggleButton
        CSButton_2                      matlab.ui.control.ToggleButton
        CSButton                        matlab.ui.control.ToggleButton
        WithshockButtonGroup            matlab.ui.container.ButtonGroup
        YesshockButton                  matlab.ui.control.ToggleButton
        NoshockButton_2                 matlab.ui.control.ToggleButton
        ShockdurationsEditField         matlab.ui.control.NumericEditField
        ShockdurationsEditFieldLabel    matlab.ui.control.Label
        InterCSintervalmaxEditField     matlab.ui.control.NumericEditField
        InterCSintervalmaxEditFieldLabel  matlab.ui.control.Label
        InterCSintervalminEditField     matlab.ui.control.NumericEditField
        InterCSintervalminEditFieldLabel  matlab.ui.control.Label
        CSLaserRewarddursEditField      matlab.ui.control.NumericEditField
        CSLaserRewarddursEditFieldLabel  matlab.ui.control.Label
        BaselinesEditField              matlab.ui.control.NumericEditField
        BaselinesEditFieldLabel         matlab.ui.control.Label
        CSDropDown_2                    matlab.ui.control.DropDown
        CSDropDown_2Label               matlab.ui.control.Label
        CSDropDown                      matlab.ui.control.DropDown
        CSDropDownLabel                 matlab.ui.control.Label
        ExperimentIDEditField           matlab.ui.control.EditField
        ExperimentIDEditFieldLabel      matlab.ui.control.Label
        FearConditioningExperimentDesignerv11REWARDPORTLabel  matlab.ui.control.Label
        BuildExperimentPanel            matlab.ui.container.Panel
        WithrewardButtonGroup           matlab.ui.container.ButtonGroup
        YesrewardButton                 matlab.ui.control.ToggleButton
        NorewardButton                  matlab.ui.control.ToggleButton
        ClearalleventsButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        P = struct; % Description
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function startt = refreshFig(app, P)
            %% setup params Start with a fresh plot
    
            dt = mean([P.min_trial_int, P.max_trial_int]);
            csdt = P.cs_dur;
            reward_dt = P.reward_dur;
            xd = P.expdesign;
            xdt.csp = [];
            xdt.csm = [];
            xdt.shock = [];
            xdt.laser = [];
            xdt.reward = [];
            
            %% for first iteration
            if P.firstfig == 1
                app.P.firtfig = 0;
                startt = P.t_baseline;
                
                for i = 1:size(xd,2)
                    if xd(1,i) == 1
                        xdt.csp = [xdt.csp; startt, startt+csdt];
                    end
                    if xd(2,i) == 1
                        xdt.csm = [xdt.csm; startt, startt+csdt];
                    end
                    if xd(3,i) == 1
                        xdt.shock = [xdt.shock; startt+csdt-P.us_dur, startt+csdt];
                    end
                    if xd(4,i) == 1
                        xdt.laser = [xdt.laser; startt, startt+csdt];
                    end 
                    if xd(5,i) == 1
                        xdt.reward = [xdt.reward; startt, startt+csdt];
                    end
                    
                    startt = startt+csdt+dt;
                end
                    %% for other iterations
            
            else
                for i = 1:size(xd,2)
                    dt = randi([P.min_trial_int, P.max_trial_int]);
                    
                    if xd(1,i) == 1
                        xdt.csp = [xdt.csp; startt, startt+csdt];
                    end
                    if xd(2,i) == 1
                        xdt.csm = [xdt.csm; startt, startt+csdt];
                    end
                    if xd(3,i) == 1
                        xdt.shock = [xdt.shock; startt+csdt-P.us_dur, startt+csdt];
                    end
                    if xd(4,i) == 1
                        xdt.laser = [xdt.laser; startt, startt+csdt];
                    end
                    if xd(5,i) == 1
                        xdt.reward = [xdt.reward; startt, startt+reward_dt];
                    end
        
                    startt = startt+csdt+dt;
                end
                
            end
            
            
            %% plot bouts
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
          
            app.UIAxes.YLim = [0,6];
            app.UIAxes.XLim = [0, startt];
            app.UIAxes.YTick = [1 2 3 4 5];
            app.UIAxes.YTickLabel = {'CS+', 'CS-', 'Shock', 'Laser', 'Reward'};
        
            % do csp
            if ~isempty(xdt.csp)
                i = 1;
                x = xdt.csp;
                x = [x, x(:,2), x(:,1)];
                y = ones(size(x)) .* [i-0.5, i-0.5, i+0.5, i+0.5];
                for j = 1:size(x,1)
                    patch(app.UIAxes, x(j,:), y(j,:), 'k');
                end
            end
            
            % do csm
            if ~isempty(xdt.csm)
                i = 2;
                x = xdt.csm;
                x = [x, x(:,2), x(:,1)];
                y = ones(size(x)) .* [i-0.5, i-0.5, i+0.5, i+0.5];
                for j = 1:size(x,1)
                    patch(app.UIAxes, x(j,:), y(j,:), 'k');
                end
            end
            
            % do shock
            if ~isempty(xdt.shock)
                i = 3;
                x = xdt.shock;
                x = [x, x(:,2), x(:,1)];
                y = ones(size(x)) .* [i-0.5, i-0.5, i+0.5, i+0.5];
                for j = 1:size(x,1)
                    patch(app.UIAxes, x(j,:), y(j,:), 'k');
                end   
            end
            
            % do laser
            if ~isempty(xdt.laser)
                i = 4;
                x = xdt.laser;
                x = [x, x(:,2), x(:,1)];
                y = ones(size(x)) .* [i-0.5, i-0.5, i+0.5, i+0.5];
                for j = 1:size(x,1)
                    patch(app.UIAxes, x(j,:), y(j,:), 'k');
                end  
            end

            if ~isempty(xdt.reward)
                i = 5;
                x = xdt.reward;
                x = [x, x(:,2), x(:,1)];
                y = ones(size(x)) .* [i-0.5, i-0.5, i+0.5, i+0.5];
                for j = 1:size(x,1)
                    patch(app.UIAxes, x(j,:), y(j,:), 'k');
                end  
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tf = 0;
            list = {'1','2','3','4','5','6','7','8','9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};

            while tf==0
                questdlg('Please select Arduino COM Port in the next dialog','', 'OK','');
                [indx,tf] = listdlg('ListString',list, 'SelectionMode', 'single', ...
                    'InitialValue', 4, 'Name', 'Arduino COM', ...
                    'CancelString', '', 'ListSize', [250 250]);
            end
            com = ['COM' num2str(indx)];
            msgbox({'Wait until the Arduino is connected before editing the settings'; '~10 seconds'});
            app.P.a = arduino(com);
            msgbox('Arduino connected');
            app.P.a.writeDigitalPin('D2',0);
            app.P.a.writeDigitalPin('D3',0);
            app.P.a.writeDigitalPin('D4',0);
            app.P.a.writeDigitalPin('D5',0);
            app.P.a.writeDigitalPin('D6',0);
            app.P.a.writeDigitalPin('D7',0);
           % D9 is reward port. Reward port activates when low, so need to
            % keep up when inactive.
            app.P.a.writeDigitalPin('D9',1);

            
            app.P.firstfig = 1;
            app.P.expdesign = [];
            app.P.t_baseline = 0;
            app.P.csp = 1; 
            app.P.csm = 0; 
            app.P.shock = 0; 
            app.P.laser = 0;
            app.P.reward = 0;
            app.P.min_trial_int = 30;
            app.P.max_trial_int = 100;
            app.P.exp_ID = 'IForgotToNameThis';
            app.P.cs_plus = 'Tone1';
            app.P.cs_minus = 'Tone1';
            app.P.tone_freq1 = 4000;
            app.P.tone_freq2 = 4000;
            app.P.start_freq1 = 2000;
            app.P.start_freq2 = 2000;
            app.P.end_freq1 = 20000;
            app.P.end_freq2 = 20000;
            app.P.sweep_dur = 1;
            app.P.flicker_freq1 = 1;
            app.P.flicker_freq2 = 1;
            app.P.light_dc1 = 0.5;
            app.P.light_dc2 = 0.5;
            app.P.cs_dur = 30;
            app.P.us_dur = 2;
            app.P.doMiniscope = 0;
            app.P.postHoldTime = 0;
            app.P.hz = 1;
            app.P.run_PG = 0;
            app.P.vidROIdefault = [];
            app.P.reward_dur = 30;
            
        end

        % Value changed function: ExperimentIDEditField
        function ExperimentIDEditFieldValueChanged(app, event)
            value = app.ExperimentIDEditField.Value;
            app.P.exp_ID = value;
        end

        % Value changed function: CSDropDown
        function CSDropDownValueChanged(app, event)
            value = app.CSDropDown.Value;
            app.P.cs_plus = value;
        end

        % Value changed function: CSDropDown_2
        function CSDropDown_2ValueChanged(app, event)
            value = app.CSDropDown_2.Value;
            app.P.cs_minus = value;
        end

        % Value changed function: CSLaserRewarddursEditField
        function CSLaserRewarddursEditFieldValueChanged(app, event)
            value = app.CSLaserRewarddursEditField.Value;
            app.P.cs_dur = value;
        end

        % Value changed function: ShockdurationsEditField
        function ShockdurationsEditFieldValueChanged(app, event)
            value = app.ShockdurationsEditField.Value;
            app.P.us_dur = value;
        end

        % Value changed function: BaselinesEditField
        function BaselinesEditFieldValueChanged(app, event)
            value = app.BaselinesEditField.Value;
            app.P.t_baseline = value;
        end

        % Value changed function: InterCSintervalminEditField
        function InterCSintervalminEditFieldValueChanged(app, event)
            value = app.InterCSintervalminEditField.Value;
            app.P.min_trial_int = value;
        end

        % Value changed function: InterCSintervalmaxEditField
        function InterCSintervalmaxEditFieldValueChanged(app, event)
            value = app.InterCSintervalmaxEditField.Value;
            app.P.max_trial_int = value;
        end

        % Value changed function: TonefreqHzEditField
        function TonefreqHzEditFieldValueChanged(app, event)
            value = app.TonefreqHzEditField.Value;
            app.P.tone_freq1 = value;
        end

        % Callback function
        function FMSweepstartHzEditFieldValueChanged(app, event)
            value = app.FMSweepstartHzEditField.Value;
            app.P.start_freq1 = value;
        end

        % Callback function
        function FMSweependHzEditFieldValueChanged(app, event)
            value = app.FMSweependHzEditField.Value;
            app.P.end_freq1 = value;
        end

        % Callback function
        function FMSweepdursEditFieldValueChanged(app, event)
            value = app.FMSweepdursEditField.Value;
            app.P.sweep_dur = value;
        end

        % Callback function
        function LightpulsefreqHzEditFieldValueChanged(app, event)
            value = app.LightpulsefreqHzEditField.Value;
            app.P.flicker_freq = value;
        end

        % Callback function
        function LightpulsedutycycleEditFieldValueChanged(app, event)
            value = app.LightpulsedutycycleEditField.Value;
            app.P.light_dc = value;
        end

        % Selection changed function: ChooseCSButtonGroup
        function ChooseCSButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseCSButtonGroup.SelectedObject;
            app.P.this_cs = selectedButton;
            if isequal(app.P.this_cs.Text, 'CS-')
                app.P.csm = 1;
                app.P.csp = 0;
            elseif isequal(app.P.this_cs.Text, 'CS+')
                app.P.csm = 0;
                app.P.csp = 1;
            elseif isequal(app.P.this_cs.Text, 'No CS')
                app.P.csm = 2;
                app.P.csp = 2;
            end

        end

        % Selection changed function: WithshockButtonGroup
        function WithshockButtonGroupSelectionChanged(app, event)
            selectedButton = app.WithshockButtonGroup.SelectedObject;
            app.P.this_shock = selectedButton;
            if isequal(app.P.this_shock.Text, 'No shock')
                app.P.shock = 0;
            else
                app.P.shock = 1;
            end
        end

        % Selection changed function: WithlaserButtonGroup
        function WithlaserButtonGroupSelectionChanged(app, event)
            selectedButton = app.WithlaserButtonGroup.SelectedObject;
            app.P.this_laser = selectedButton;
            if isequal(app.P.this_laser.Text, 'No laser')
                app.P.laser = 0;
            else
                app.P.laser = 1;
            end
        end

        % Button pushed function: AddeventButton
        function AddeventButtonPushed(app, event)
            if isequal([app.P.csp; app.P.csm; app.P.shock; app.P.laser; app.P.reward], [2;2;0;0;0])
                warndlg('Invalid selection. An event cannot be empty.', 'ut oh')
            else
                app.P.expdesign = [app.P.expdesign, [app.P.csp; app.P.csm; app.P.shock; app.P.laser; app.P.reward]];
                app.P.startt = refreshFig(app, app.P);
            end
        end

        % Value changed function: TonefreqHzEditField_2
        function TonefreqHzEditField_2ValueChanged(app, event)
            value = app.TonefreqHzEditField_2.Value;
            app.P.tone_freq2 = value;
        end

        % Value changed function: FMSweepstartHzEditField_2
        function FMSweepstartHzEditField_2ValueChanged(app, event)
            value = app.FMSweepstartHzEditField_2.Value;
            app.P.start_freq1 = value;
        end

        % Value changed function: FMSweepstartHzEditField_3
        function FMSweepstartHzEditField_3ValueChanged(app, event)
            value = app.FMSweepstartHzEditField_3.Value;
            app.P.start_freq2 = value;
        end

        % Value changed function: FMSweependHzEditField_2
        function FMSweependHzEditField_2ValueChanged(app, event)
            value = app.FMSweependHzEditField_2.Value;
            app.P.end_freq1 = value;
        end

        % Value changed function: FMSweepdursEditField_2
        function FMSweepdursEditField_2ValueChanged(app, event)
            value = app.FMSweepdursEditField_2.Value;
            app.P.sweep_dur = value;
        end

        % Value changed function: FMSweependHzEditField_3
        function FMSweependHzEditField_3ValueChanged(app, event)
            value = app.FMSweependHzEditField_3.Value;
            app.P.end_freq2 = value;
        end

        % Value changed function: FMSweepdursEditField_3
        function FMSweepdursEditField_3ValueChanged(app, event)
            value = app.FMSweepdursEditField_3.Value;
            app.P.sweep_dur = value;
        end

        % Callback function
        function LightpulsefreqHzEditField_2ValueChanged(app, event)
            value = app.LightpulsefreqHzEditField_2.Value;
            app.P.flicker_freq1 = value;
        end

        % Callback function
        function LightpulsedutycycleEditField_2ValueChanged(app, event)
            value = app.LightpulsedutycycleEditField_2.Value;
            app.P.light_dc1 = value;
        end

        % Callback function
        function LightpulsefreqHzEditField_3ValueChanged(app, event)
            value = app.LightpulsefreqHzEditField_3.Value;
            app.P.flicker_freq2 = value;
        end

        % Callback function
        function LightpulsedutycycleEditField_3ValueChanged(app, event)
            value = app.LightpulsedutycycleEditField_3.Value;
            app.P.light_dc2 = value;
        end

        % Button pushed function: LaunchExperimentButton
        function LaunchExperimentButtonPushed(app, event)
            launch_fc_reward(app.P)
        end

        % Button pushed function: ClearalleventsButton
        function ClearalleventsButtonPushed(app, event)
            app.P.expdesign = [];
            app.P.startt = refreshFig(app, app.P);
        end

        % Button pushed function: EasterEgg
        function EasterEggPushed(app, event)
            msgbox('"All outstanding work, in art as well as in science, results from immense zeal applied to a great idea.  – Santiago Ramón y Cajal', 'Good luck with the experiment')
        end

        % Value changed function: DoMiniscope
        function DoMiniscopeValueChanged(app, event)
            value = app.DoMiniscope.Value;
            app.P.doMiniscope = value;
        end

        % Value changed function: PostexperimentholdtimesEditField
        function PostexperimentholdtimesEditFieldValueChanged(app, event)
            value = app.PostexperimentholdtimesEditField.Value;
            app.P.postHoldTime = value;
        end

        % Button pushed function: HelpRemindersButton
        function HelpRemindersButtonPushed(app, event)
            msgbox({'DEFAULT PIN ARRANGEMENTS - MAY HAVE BEEN CHANGED'; 'tone pin = D3; shock pin = D4; opto pin = D6; light pin = D7, reward pump pin = D9, reward light pin = D10, miniscope trigger = D13'; 'trigger for filtered white noise:  tone freq == 1'; 'to find Arduino COM #, check Device Manager under "COM" '; 'Reward only duration is length of event when reward is the only action triggered. Reward + other event will have CS duration'})
        end

        % Callback function
        function FMSweepfreqHzEditFieldValueChanged(app, event)
            value = app.FMSweepfreqHzEditField.Value;
            app.P.hz = value;
        end

        % Callback function
        function FMSweepfreqHzEditField_2ValueChanged(app, event)
            value = app.FMSweepfreqHzEditField_2.Value;
            app.P.hz = value;
        end

        % Button pushed function: PreviewPGButton
        function PreviewPGButtonPushed(app, event)
            if  isfield(app.P, 'vid')
                preview(app.P.vid);
            else
            app.P.vid = videoinput('pointgrey', 1, 'F7_Mono8_1024x768_Mode1');
            app.P.src = getselectedsource(app.P.vid);
            app.P.vid.FramesPerTrigger = Inf;
            app.P.src.ExposureMode = 'Auto';
            app.P.src.FrameRate = 50;
            app.P.src.GainMode = 'Auto';
            app.P.src.ShutterMode = 'Auto';
            preview(app.P.vid);

            end

        end

        % Value changed function: RunPGCheckBox
        function RunPGCheckBoxValueChanged(app, event)
            value = app.RunPGCheckBox.Value;
            app.P.run_PG = value;
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            if isfield(app.P, 'vid')
                frame = getsnapshot(app.P.vid);
            else
                app.P.vid = videoinput('pointgrey', 1, 'F7_Mono8_1024x768_Mode1');
                app.P.src = getselectedsource(app.P.vid);
                app.P.vid.FramesPerTrigger = Inf;
                app.P.src.ExposureMode = 'Auto';
                app.P.src.FrameRate = 50;
                app.P.src.GainMode = 'Auto';
                app.P.src.ShutterMode = 'Auto';
                frame = getsnapshot(app.P.vid);
            end
            imshow(frame);
            hold on
            roi = drawrectangle;
            app.P.vidROIdefault = app.P.vid.ROIPosition;
            app.P.vid.ROIPosition = roi.Position;
            close;
        end

        % Button pushed function: ResetROIButton
        function ResetROIButtonPushed(app, event)
             if isfield(app.P, 'vid')
                app.P.vid.ROIPosition = app.P.vidROIdefault;
             end
        end

        % Selection changed function: WithrewardButtonGroup
        function WithrewardButtonGroupSelectionChanged(app, event)
            selectedButton = app.WithrewardButtonGroup.SelectedObject;
            app.P.this_reward = selectedButton;
            if isequal(app.P.this_reward.Text, 'No reward')
                app.P.reward = 0;
            else
                app.P.reward = 1;
            end
        end

        % Value changed function: RewardonlydurationsEditField
        function RewardonlydurationsEditFieldValueChanged(app, event)
            value = app.RewardonlydurationsEditField.Value;
            app.P.reward_dur = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Experiment Visualization')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Event')
            zlabel(app.UIAxes, 'Z')
            app.BuildExperimentPanel = uipanel(app.UIFigure);
            app.BuildExperimentPanel.Title = 'Build Experiment';
            app.ClearalleventsButton = uibutton(app.BuildExperimentPanel, 'push');
            app.ClearalleventsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearalleventsButtonPushed, true);
            app.ClearalleventsButton.Text = 'Clear all events';
            app.WithrewardButtonGroup = uibuttongroup(app.BuildExperimentPanel);
            app.WithrewardButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WithrewardButtonGroupSelectionChanged, true);
            app.WithrewardButtonGroup.Title = 'With reward?';
            app.NorewardButton = uitogglebutton(app.WithrewardButtonGroup);
            app.NorewardButton.Text = 'No reward';
            app.NorewardButton.Value = true;
            app.YesrewardButton = uitogglebutton(app.WithrewardButtonGroup);
            app.YesrewardButton.Text = 'Yes reward';
            app.FearConditioningExperimentDesignerv11REWARDPORTLabel = uilabel(app.UIFigure);
            app.FearConditioningExperimentDesignerv11REWARDPORTLabel.Text = 'Fear Conditioning Experiment Designer v1.1 - REWARD PORT';
            app.ExperimentIDEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentIDEditFieldLabel.Text = 'Experiment ID';
            app.ExperimentIDEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentIDEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentIDEditFieldValueChanged, true);
            app.CSDropDownLabel = uilabel(app.UIFigure);
            app.CSDropDownLabel.Text = 'CS +';
            app.CSDropDown = uidropdown(app.UIFigure);
            app.CSDropDown.Items = {'Tone1', 'Tone2', 'FM Sweep1', 'FM Sweep2', 'Light', 'Pulsed Light1', 'Pulsed Light2'};
            app.CSDropDown.ValueChangedFcn = createCallbackFcn(app, @CSDropDownValueChanged, true);
            app.CSDropDown.Value = 'Tone1';
            app.CSDropDown_2Label = uilabel(app.UIFigure);
            app.CSDropDown_2Label.Text = 'CS -';
            app.CSDropDown_2 = uidropdown(app.UIFigure);
            app.CSDropDown_2.Items = {'Tone1', 'Tone2', 'FM Sweep1', 'FM Sweep2', 'Light', 'Pulsed Light1', 'Pulsed Light2'};
            app.CSDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CSDropDown_2ValueChanged, true);
            app.CSDropDown_2.Value = 'Tone1';
            app.BaselinesEditFieldLabel = uilabel(app.UIFigure);
            app.BaselinesEditFieldLabel.Text = 'Baseline (s)';
            app.BaselinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselinesEditField.ValueChangedFcn = createCallbackFcn(app, @BaselinesEditFieldValueChanged, true);
            app.CSLaserRewarddursEditFieldLabel = uilabel(app.UIFigure);
            app.CSLaserRewarddursEditFieldLabel.Text = 'CS + Laser + Reward dur (s)';
            app.CSLaserRewarddursEditField = uieditfield(app.UIFigure, 'numeric');
            app.CSLaserRewarddursEditField.ValueChangedFcn = createCallbackFcn(app, @CSLaserRewarddursEditFieldValueChanged, true);
            app.InterCSintervalminEditFieldLabel = uilabel(app.UIFigure);
            app.InterCSintervalminEditFieldLabel.Text = 'Inter-CS interval min';
            app.InterCSintervalminEditField = uieditfield(app.UIFigure, 'numeric');
            app.InterCSintervalminEditField.ValueChangedFcn = createCallbackFcn(app, @InterCSintervalminEditFieldValueChanged, true);
            app.InterCSintervalmaxEditFieldLabel = uilabel(app.UIFigure);
            app.InterCSintervalmaxEditFieldLabel.Text = 'Inter-CS interval max';
            app.InterCSintervalmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.InterCSintervalmaxEditField.ValueChangedFcn = createCallbackFcn(app, @InterCSintervalmaxEditFieldValueChanged, true);
            app.ShockdurationsEditFieldLabel = uilabel(app.UIFigure);
            app.ShockdurationsEditFieldLabel.Text = 'Shock duration (s)';
            app.ShockdurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShockdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @ShockdurationsEditFieldValueChanged, true);
            app.WithshockButtonGroup = uibuttongroup(app.UIFigure);
            app.WithshockButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WithshockButtonGroupSelectionChanged, true);
            app.WithshockButtonGroup.Title = 'With shock?';
            app.NoshockButton_2 = uitogglebutton(app.WithshockButtonGroup);
            app.NoshockButton_2.Text = 'No shock';
            app.NoshockButton_2.Value = true;
            app.YesshockButton = uitogglebutton(app.WithshockButtonGroup);
            app.YesshockButton.Text = 'Yes shock';
            app.ChooseCSButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseCSButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseCSButtonGroupSelectionChanged, true);
            app.ChooseCSButtonGroup.Title = 'Choose CS';
            app.CSButton = uitogglebutton(app.ChooseCSButtonGroup);
            app.CSButton.Text = 'CS+';
            app.CSButton.Value = true;
            app.CSButton_2 = uitogglebutton(app.ChooseCSButtonGroup);
            app.CSButton_2.Text = 'CS-';
            app.NoCSButton_2 = uitogglebutton(app.ChooseCSButtonGroup);
            app.NoCSButton_2.Text = 'No CS';
            app.WithlaserButtonGroup = uibuttongroup(app.UIFigure);
            app.WithlaserButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WithlaserButtonGroupSelectionChanged, true);
            app.WithlaserButtonGroup.Title = 'With laser?';
            app.NolaserButton = uitogglebutton(app.WithlaserButtonGroup);
            app.NolaserButton.Text = 'No laser';
            app.NolaserButton.Value = true;
            app.YeslaserButton = uitogglebutton(app.WithlaserButtonGroup);
            app.YeslaserButton.Text = 'Yes laser';
            app.AddeventButton = uibutton(app.UIFigure, 'push');
            app.AddeventButton.ButtonPushedFcn = createCallbackFcn(app, @AddeventButtonPushed, true);
            app.AddeventButton.Text = 'Add event';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tone1Tab = uitab(app.TabGroup);
            app.Tone1Tab.Title = 'Tone1';
            app.TonefreqHzEditFieldLabel = uilabel(app.Tone1Tab);
            app.TonefreqHzEditFieldLabel.Text = 'Tone freq (Hz)';
            app.TonefreqHzEditField = uieditfield(app.Tone1Tab, 'numeric');
            app.TonefreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @TonefreqHzEditFieldValueChanged, true);
            app.Tone2Tab = uitab(app.TabGroup);
            app.Tone2Tab.Title = 'Tone2';
            app.TonefreqHzEditField_2Label = uilabel(app.Tone2Tab);
            app.TonefreqHzEditField_2Label.Text = 'Tone freq (Hz)';
            app.TonefreqHzEditField_2 = uieditfield(app.Tone2Tab, 'numeric');
            app.TonefreqHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @TonefreqHzEditField_2ValueChanged, true);
            app.FM1Tab = uitab(app.TabGroup);
            app.FM1Tab.Title = 'FM1';
            app.FMSweepstartHzEditField_2Label = uilabel(app.FM1Tab);
            app.FMSweepstartHzEditField_2Label.Text = 'FM Sweep start (Hz)';
            app.FMSweepstartHzEditField_2 = uieditfield(app.FM1Tab, 'numeric');
            app.FMSweepstartHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @FMSweepstartHzEditField_2ValueChanged, true);
            app.FMSweependHzEditField_2Label = uilabel(app.FM1Tab);
            app.FMSweependHzEditField_2Label.Text = 'FM Sweep end (Hz)';
            app.FMSweependHzEditField_2 = uieditfield(app.FM1Tab, 'numeric');
            app.FMSweependHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @FMSweependHzEditField_2ValueChanged, true);
            app.FMSweepdursEditField_2Label = uilabel(app.FM1Tab);
            app.FMSweepdursEditField_2Label.Text = 'FM Sweep dur (s)';
            app.FMSweepdursEditField_2 = uieditfield(app.FM1Tab, 'numeric');
            app.FMSweepdursEditField_2.ValueChangedFcn = createCallbackFcn(app, @FMSweepdursEditField_2ValueChanged, true);
            app.FM2Tab = uitab(app.TabGroup);
            app.FM2Tab.Title = 'FM2';
            app.FMSweepstartHzEditField_3Label = uilabel(app.FM2Tab);
            app.FMSweepstartHzEditField_3Label.Text = 'FM Sweep start (Hz)';
            app.FMSweepstartHzEditField_3 = uieditfield(app.FM2Tab, 'numeric');
            app.FMSweepstartHzEditField_3.ValueChangedFcn = createCallbackFcn(app, @FMSweepstartHzEditField_3ValueChanged, true);
            app.FMSweependHzEditField_3Label = uilabel(app.FM2Tab);
            app.FMSweependHzEditField_3Label.Text = 'FM Sweep end (Hz)';
            app.FMSweependHzEditField_3 = uieditfield(app.FM2Tab, 'numeric');
            app.FMSweependHzEditField_3.ValueChangedFcn = createCallbackFcn(app, @FMSweependHzEditField_3ValueChanged, true);
            app.FMSweepdursEditField_3Label = uilabel(app.FM2Tab);
            app.FMSweepdursEditField_3Label.Text = 'FM Sweep dur (s)';
            app.FMSweepdursEditField_3 = uieditfield(app.FM2Tab, 'numeric');
            app.FMSweepdursEditField_3.ValueChangedFcn = createCallbackFcn(app, @FMSweepdursEditField_3ValueChanged, true);
            app.LaunchExperimentButton = uibutton(app.UIFigure, 'push');
            app.LaunchExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchExperimentButtonPushed, true);
            app.LaunchExperimentButton.Text = 'Launch Experiment';
            app.EasterEgg = uibutton(app.UIFigure, 'push');
            app.EasterEgg.ButtonPushedFcn = createCallbackFcn(app, @EasterEggPushed, true);
            app.DoMiniscope = uicheckbox(app.UIFigure);
            app.DoMiniscope.ValueChangedFcn = createCallbackFcn(app, @DoMiniscopeValueChanged, true);
            app.DoMiniscope.Text = '';
            app.DoMiniscopeEditField = uilabel(app.UIFigure);
            app.DoMiniscopeEditField.Text = 'Trigger miniscope';
            app.PostexperimentholdtimesEditFieldLabel = uilabel(app.UIFigure);
            app.PostexperimentholdtimesEditFieldLabel.Text = 'Post-experiment hold time (s)';
            app.PostexperimentholdtimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PostexperimentholdtimesEditField.ValueChangedFcn = createCallbackFcn(app, @PostexperimentholdtimesEditFieldValueChanged, true);
            app.HelpRemindersButton = uibutton(app.UIFigure, 'push');
            app.HelpRemindersButton.ButtonPushedFcn = createCallbackFcn(app, @HelpRemindersButtonPushed, true);
            app.HelpRemindersButton.Text = 'Help & Reminders';
            app.PreviewPGButton = uibutton(app.UIFigure, 'push');
            app.PreviewPGButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewPGButtonPushed, true);
            app.PreviewPGButton.Text = 'Preview PG';
            app.RunPGCheckBox = uicheckbox(app.UIFigure);
            app.RunPGCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunPGCheckBoxValueChanged, true);
            app.RunPGCheckBox.Text = 'Run PG';
            app.DrawROIButton = uibutton(app.UIFigure, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'Draw ROI';
            app.ResetROIButton = uibutton(app.UIFigure, 'push');
            app.ResetROIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROIButtonPushed, true);
            app.ResetROIButton.Text = 'Reset ROI';
            app.RewardonlydurationsEditFieldLabel = uilabel(app.UIFigure);
            app.RewardonlydurationsEditFieldLabel.Text = 'Reward only duration (s)';
            app.RewardonlydurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RewardonlydurationsEditField.ValueChangedFcn = createCallbackFcn(app, @RewardonlydurationsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FCXD_PGfork_Rewardfork

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end